classdef app_anemia_types_classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnemiaTypesPredictionfromCBCDataUIFigure  matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        FilldatawithDropDown         matlab.ui.control.DropDown
        FilldatawithDropDownLabel    matlab.ui.control.Label
        ShowPredictiononGraphButton  matlab.ui.control.Button
        NEUTnEditField               matlab.ui.control.NumericEditField
        NEUTnEditFieldLabel          matlab.ui.control.Label
        LYMnEditField                matlab.ui.control.NumericEditField
        LYMnEditFieldLabel           matlab.ui.control.Label
        NEUTpEditField               matlab.ui.control.NumericEditField
        NEUTpEditFieldLabel          matlab.ui.control.Label
        LYMpEditField                matlab.ui.control.NumericEditField
        LYMpEditFieldLabel           matlab.ui.control.Label
        PCTEditField                 matlab.ui.control.NumericEditField
        PCTEditFieldLabel            matlab.ui.control.Label
        PDWEditField                 matlab.ui.control.NumericEditField
        PDWEditFieldLabel            matlab.ui.control.Label
        x1000permicroliterLabel_4    matlab.ui.control.Label
        x1000permicroliterLabel_3    matlab.ui.control.Label
        percentsLabel_4              matlab.ui.control.Label
        percentsLabel_3              matlab.ui.control.Label
        nanogramspermililiterLabel   matlab.ui.control.Label
        percentsLabel                matlab.ui.control.Label
        MCHCEditField                matlab.ui.control.NumericEditField
        MCHCEditFieldLabel           matlab.ui.control.Label
        MCHEditField                 matlab.ui.control.NumericEditField
        MCHEditFieldLabel            matlab.ui.control.Label
        MCVEditField                 matlab.ui.control.NumericEditField
        MCVEditFieldLabel            matlab.ui.control.Label
        HCTEditField                 matlab.ui.control.NumericEditField
        HCTEditFieldLabel            matlab.ui.control.Label
        gramsperdeciliterLabel_2     matlab.ui.control.Label
        picogramspercellLabel        matlab.ui.control.Label
        femtolitersLabel             matlab.ui.control.Label
        percentsLabel_2              matlab.ui.control.Label
        RBCEditField                 matlab.ui.control.NumericEditField
        RBCEditFieldLabel            matlab.ui.control.Label
        x1000000permicroliterLabel   matlab.ui.control.Label
        WBCEditField                 matlab.ui.control.NumericEditField
        WBCEditFieldLabel            matlab.ui.control.Label
        x1000permicroliterLabel      matlab.ui.control.Label
        PLTEditField                 matlab.ui.control.NumericEditField
        PLTEditFieldLabel            matlab.ui.control.Label
        x1000permicroliterLabel_2    matlab.ui.control.Label
        gramsperdeciliterLabel       matlab.ui.control.Label
        HGBEditField                 matlab.ui.control.NumericEditField
        HGBEditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MdlEns; % Ensemble model
        MdlTree; % Tree model
    end
    
    methods (Access = private)
        
        % sets the EditField with values of a vector
        function fillData(app, a)
            app.HGBEditField.Value = a(1);
            app.PLTEditField.Value = a(2);
            app.WBCEditField.Value = a(3);
            app.RBCEditField.Value = a(4);
            app.HCTEditField.Value = a(5);
            app.MCVEditField.Value = a(6);
            app.MCHEditField.Value = a(7);
            app.MCHCEditField.Value = a(8);
            app.PDWEditField.Value = a(9);
            app.PCTEditField.Value = a(10);
            app.LYMpEditField.Value = a(11);
            app.NEUTpEditField.Value = a(12);
            app.LYMnEditField.Value = a(13);
            app.NEUTnEditField.Value = a(14);
        end

        % reorder the vector element to be suitable for EditFields
        function results = table2app(~, a)
           % - table row order: 
           % WBC, LYMp, NEUTp, LYMn, NEUTn, 
           % RBC, HGB, HCT, MCV, MCH,
           % MCHC, PLT, PDW, PCT
           % - app fields order: 
           % HGB, PLT, WBC, RBC, HTC, 
           % MCV, MCH, MCHC, PWD, PCT, 
           % LYMp, NEUTp, LYMn, NEUTn
           results = [a(7), a(12), a(1), a(6), a(8), ...
                      a(9), a(10), a(11), a(13), a(14), ...
                      a(2), a(3), a(4), a(5)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load Ensemble and Tree models
            loaded_Ensemble = load("trainedModel_SMOTE_Ensemble.mat");
            loaded_Tree = load("trainedModel_SMOTE_Tree.mat");
            app.MdlEns = loaded_Ensemble.trainedModel_SMOTE_Ensemble.ClassificationEnsemble;
            app.MdlTree = loaded_Tree.trainedModel_SMOTE_Tree.ClassificationTree;
            % draw empty graph   
            resTable = array2table(app.MdlEns.ClassNames,"VariableNames","Class");
            resTable.("scoreTree") = zeros(9,1);
            resTable.("scoreEns") = zeros(9,1);
            % graph            
            b = bar(app.UIAxes, resTable{:,2:3},Horizontal="on");
            b(1).FaceColor = [0 1 0];
            b(2).FaceColor = [1 0 0];
            xtickformat(app.UIAxes,'percentage');
            yticklabels(app.UIAxes, resTable{:,1});
            xlim(app.UIAxes,[0,100]);
            xlabel(app.UIAxes,"");
            ylabel(app.UIAxes,"");
            title(app.UIAxes, {"Likelihood of the Anemia Types","Based on the Ensemble and Tree Models"});
            legend(app.UIAxes, {"Tree", "Ensemble"}, 'Location','southeast');
            % add items to dropdown box
            for i=1:9
                app.FilldatawithDropDown.Items = [ app.FilldatawithDropDown.Items, ...
                    "Averages of " + string(app.MdlEns.ClassNames(i))];
            end
            app.FilldatawithDropDown.Items = [app.FilldatawithDropDown.Items, ...
                "Random Observation"];
        end

        % Button pushed function: ShowPredictiononGraphButton
        function ShowPredictiononGraphButtonPushed(app, event)
            Xnew = [app.WBCEditField.Value, ...
                    app.LYMpEditField.Value, ...
                    app.NEUTpEditField.Value, ...
                    app.LYMnEditField.Value, ...
                    app.NEUTnEditField.Value, ...
                    app.RBCEditField.Value, ...
                    app.HGBEditField.Value, ...
                    app.HCTEditField.Value, ...
                    app.MCVEditField.Value, ...              
                    app.MCHEditField.Value, ...
                    app.MCHCEditField.Value, ...
                    app.PLTEditField.Value, ...
                    app.PDWEditField.Value, ...
                    app.PCTEditField.Value];
            % creating table with class names
            resTable = array2table(app.MdlEns.ClassNames,"VariableNames","Class");
            % predictions
            [~,scoreEns] = predict(app.MdlEns,Xnew);
            [~,scoreTree] = predict(app.MdlTree,Xnew);
            % adding predictions (confidence intervals) to the table
            resTable.("scoreTree") = 100 * scoreTree';
            resTable.("scoreEns") = 100 * scoreEns';
            % sorting the table by confidence intervals
            resTable = sortrows(resTable,"scoreEns","ascend");
            % graph            
            b = bar(app.UIAxes, resTable{:,2:3},Horizontal="on");
            b(1).FaceColor = [0 1 0];
            b(2).FaceColor = [1 0 0];
            xtickformat(app.UIAxes,'percentage');
            yticklabels(app.UIAxes, resTable{:,1});
            xlim(app.UIAxes,[0,100]);
            xlabel(app.UIAxes,"");
            ylabel(app.UIAxes,"");         
            title(app.UIAxes, {"Likelihood of the Anemia Types","Based on the Ensemble and Tree Models"});
            legend(app.UIAxes, {"Tree", "Ensemble"}, 'Location','southeast');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            value = app.FilldatawithDropDown.Value;
            if value == "Zeros"
                fillData(app, zeros(1,14));
            end
            if value == "Random Observation"
                obsNum = randi([1,size(app.MdlEns.X,1)]);
                obs = table2array(app.MdlEns.X(obsNum,:));
                obs = round(obs,4);
                obs = app.table2app(obs);
                app.fillData(obs);
            end
            for i = 1:size(app.MdlEns.ClassNames,1)
                class = app.MdlEns.ClassNames(i);
                if value == "Averages of " + string(class)
                    avg = table2array(mean(app.MdlEns.X(app.MdlEns.Y == class, :)));
                    avg = round(avg,4);
                    avg = app.table2app(avg);
                    app.fillData(avg);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnemiaTypesPredictionfromCBCDataUIFigure = uifigure('Visible', 'off');
            app.AnemiaTypesPredictionfromCBCDataUIFigure.Name = 'Anemia Types Prediction from CBC Data';
            app.UIAxes = uiaxes(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HGBEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.HGBEditFieldLabel.Text = 'HGB';
            app.HGBEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.HGBEditField.ValueDisplayFormat = '%11.10g';
            app.gramsperdeciliterLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.gramsperdeciliterLabel.Text = 'grams per deciliter';
            app.x1000permicroliterLabel_2 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.x1000permicroliterLabel_2.Text = 'x1,000 per microliter';
            app.PLTEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.PLTEditFieldLabel.Text = 'PLT';
            app.PLTEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.PLTEditField.ValueDisplayFormat = '%11.10g';
            app.x1000permicroliterLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.x1000permicroliterLabel.Text = 'x1,000 per microliter';
            app.WBCEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.WBCEditField.ValueDisplayFormat = '%11.10g';
            app.x1000000permicroliterLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.x1000000permicroliterLabel.Text = 'x1,000,000 per microliter';
            app.RBCEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.RBCEditField.ValueDisplayFormat = '%11.10g';
            app.percentsLabel_2 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.percentsLabel_2.Text = 'percents';
            app.femtolitersLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.femtolitersLabel.Text = 'femtoliters';
            app.picogramspercellLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.picogramspercellLabel.Text = 'picograms per cell';
            app.gramsperdeciliterLabel_2 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.gramsperdeciliterLabel_2.Text = 'grams per deciliter';
            app.HCTEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.HCTEditFieldLabel.Text = 'HCT';
            app.HCTEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.HCTEditField.ValueDisplayFormat = '%11.10g';
            app.MCVEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.MCVEditFieldLabel.Text = 'MCV';
            app.MCVEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.MCVEditField.ValueDisplayFormat = '%11.10g';
            app.MCHEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.MCHEditFieldLabel.Text = 'MCH';
            app.MCHEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.MCHEditField.ValueDisplayFormat = '%11.10g';
            app.MCHCEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.MCHCEditFieldLabel.Text = 'MCHC';
            app.MCHCEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.MCHCEditField.ValueDisplayFormat = '%11.10g';
            app.percentsLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.percentsLabel.Text = 'percents';
            app.nanogramspermililiterLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.nanogramspermililiterLabel.Text = 'nanograms per mililiter';
            app.percentsLabel_3 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.percentsLabel_3.Text = 'percents';
            app.percentsLabel_4 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.percentsLabel_4.Text = 'percents';
            app.x1000permicroliterLabel_3 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.x1000permicroliterLabel_3.Text = 'x1,000 per microliter';
            app.x1000permicroliterLabel_4 = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.x1000permicroliterLabel_4.Text = 'x1,000 per microliter';
            app.PDWEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.PDWEditFieldLabel.Text = 'PDW';
            app.PDWEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.PDWEditField.ValueDisplayFormat = '%11.10g';
            app.PCTEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.PCTEditFieldLabel.Text = 'PCT';
            app.PCTEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.PCTEditField.ValueDisplayFormat = '%11.10g';
            app.LYMpEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.LYMpEditFieldLabel.Text = 'LYMp';
            app.LYMpEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.LYMpEditField.ValueDisplayFormat = '%11.10g';
            app.NEUTpEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.NEUTpEditFieldLabel.Text = 'NEUTp';
            app.NEUTpEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.NEUTpEditField.ValueDisplayFormat = '%11.10g';
            app.LYMnEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.LYMnEditFieldLabel.Text = 'LYMn';
            app.LYMnEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.LYMnEditField.ValueDisplayFormat = '%11.10g';
            app.NEUTnEditFieldLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.NEUTnEditFieldLabel.Text = 'NEUTn';
            app.NEUTnEditField = uieditfield(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'numeric');
            app.NEUTnEditField.ValueDisplayFormat = '%11.10g';
            app.ShowPredictiononGraphButton = uibutton(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'push');
            app.ShowPredictiononGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPredictiononGraphButtonPushed, true);
            app.ShowPredictiononGraphButton.Text = 'Show Prediction on Graph';
            app.FilldatawithDropDownLabel = uilabel(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.FilldatawithDropDownLabel.Text = 'Fill data with:';
            app.FilldatawithDropDown = uidropdown(app.AnemiaTypesPredictionfromCBCDataUIFigure);
            app.FilldatawithDropDown.Items = {'Zeros'};
            app.FilldatawithDropDown.Value = 'Zeros';
            app.OKButton = uibutton(app.AnemiaTypesPredictionfromCBCDataUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.AnemiaTypesPredictionfromCBCDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_anemia_types_classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnemiaTypesPredictionfromCBCDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnemiaTypesPredictionfromCBCDataUIFigure)
        end
    end
end